classdef visualize_data_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TimestepSlider       matlab.ui.control.Slider
        TimestepSliderLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            value = round(app.TimestepSlider.Value); % round slider values
            plot(app.UIAxes, value*peaks); hold on
            app.UIAxes.YLim = [1 100];
            app.TimestepSlider.MajorTicks = [0:10:100]; % slider ticks
            load("alldata"); % load project data into app
            xn = alldata(2).xn; % define vars
            icur = xn(:,:,value); % 2D matrix of data at timestep 50
            imagesc(app.UIAxes, icur)
            axis(app.UIAxes,[0 size(icur,2) 0 size(icur,1)])
            colormap(app.UIAxes,"linspecer"); colorbar(app.UIAxes); % colormap & colorbar
            caxis(app.UIAxes,[min(xn(:)) max(xn(:))]) % set constant axis
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cumulative Displacement')
            xlabel(app.UIAxes, 'X-Position')
            ylabel(app.UIAxes, 'Y-Position')
            zlabel(app.UIAxes, 'Z')
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_data_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end